classdef SpectrumAcquisition_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        axes1                  matlab.ui.control.UIAxes
        axes2                  matlab.ui.control.UIAxes
        edit1                  matlab.ui.control.EditField
        edit3                  matlab.ui.control.EditField
        edit4                  matlab.ui.control.EditField
        edit2                  matlab.ui.control.EditField
        text1                  matlab.ui.control.Label
        text2                  matlab.ui.control.Label
        pushbutton1            matlab.ui.control.Button
        text3                  matlab.ui.control.Label
        pushbutton3            matlab.ui.control.Button
        listbox1               matlab.ui.control.ListBox
        text4                  matlab.ui.control.Label
        pushbutton5            matlab.ui.control.Button
        uitable1               matlab.ui.control.Table
        text5                  matlab.ui.control.Label
        checkbox1              matlab.ui.control.CheckBox
        checkbox2              matlab.ui.control.CheckBox
        checkbox3              matlab.ui.control.CheckBox
        pushbutton8            matlab.ui.control.Button
        pushbutton9            matlab.ui.control.Button
        pushbutton10           matlab.ui.control.Button
        checkbox4              matlab.ui.control.CheckBox
        text6                  matlab.ui.control.Label
        uipanel1               matlab.ui.container.Panel
        text7                  matlab.ui.control.Label
        pushbutton11           matlab.ui.control.Button
        pushbutton12           matlab.ui.control.Button
        uipanel2               matlab.ui.container.Panel
        text8                  matlab.ui.control.Label
        text9                  matlab.ui.control.Label
        edit5                  matlab.ui.control.EditField
        edit6                  matlab.ui.control.EditField
        text10                 matlab.ui.control.Label
        edit7                  matlab.ui.control.EditField
        text11                 matlab.ui.control.Label
        edit8                  matlab.ui.control.EditField
        ButtonGroup            matlab.ui.container.ButtonGroup
        EmissionButton         matlab.ui.control.RadioButton
        SynchronousButton      matlab.ui.control.RadioButton
        RamanButton            matlab.ui.control.RadioButton
        text12                 matlab.ui.control.Label
        pushbutton14           matlab.ui.control.Button
        edit9                  matlab.ui.control.EditField
        edit10                 matlab.ui.control.EditField
        MedianFilterCheckBox   matlab.ui.control.CheckBox
        edit11                 matlab.ui.control.EditField
        MonochromatorCheckBox  matlab.ui.control.CheckBox
        pushbutton6            matlab.ui.control.Button
        pushbutton7            matlab.ui.control.Button
        checkbox5              matlab.ui.control.CheckBox
        pushbutton13           matlab.ui.control.Button
        text13                 matlab.ui.control.Label
        pushbutton15           matlab.ui.control.Button
        pushbutton16           matlab.ui.control.Button
        pushbutton17           matlab.ui.control.Button
    end

    
    methods (Access = private)
        function success = CommunicationCheck(~, command)
            % global variables
            global Spectrometer; global AVERAGE; global INTEGRATIONTIME;
            success = 1;    % flag for successful communication
            switch(command)
                % baud rate
                case 'K'
                % query the spectrometer for baud rate
                fprintf(Spectrometer,'?K');
                % receive message
                fscanf(Spectrometer);
                fscanf(Spectrometer);
                num = str2double(fscanf(Spectrometer));
                if(num ~= 0)    % 115200 bits per second
                    success = 0;
                end
                % now flush the rest of the buffer
                flushinput(Spectrometer);
                
                % ASCII mode
                case 'a'
                    % query the spectrometer for mode
                    fprintf(Spectrometer,'?a');
                    % receive message
                    fscanf(Spectrometer);
                    fscanf(Spectrometer);
                    num = str2double(fscanf(Spectrometer));    % check ASCII bit
                    if(~num)    % binary mode
                        success = 0;
                    end
                    % now flush the rest of the buffer
                    flushinput(Spectrometer);
          
                % integration time
                case 'I'
                    % query the spectrometer for integration time
                    fprintf(Spectrometer,'?I');
                    % receive message
                    fscanf(Spectrometer);
                    fscanf(Spectrometer);
                    num = str2double(fscanf(Spectrometer)); % check integration time
                    if(num ~= INTEGRATIONTIME)    % query failed
                        success = 0;
                    end
                    % now flush the rest of the buffer
                    flushinput(Spectrometer);
                
                % averages    
                case 'A'
                    % query the spectrometer for averages
                    fprintf(Spectrometer,'?A');
                    % receive message
                    fscanf(Spectrometer);
                    fscanf(Spectrometer);
                    num = str2double(fscanf(Spectrometer)); % check averages
                    if(num ~= AVERAGE)    % query failed
                        success = 0;
                    end
                    % now flush the rest of the buffer
                    flushinput(Spectrometer);
            end
        end
        
        function disable(app, flag)
            switch(flag)
                case 0
                    str = 'off';
                case 1
                    str = 'on';
            end
            % now, enable/disable various fields within GUI
            app.edit5.Enable = str;
            app.edit6.Enable = str;
            app.edit9.Enable = str;
            app.edit10.Enable = str;
            app.pushbutton14.Enable = str;
            app.EmissionButton.Enable = str;
            app.SynchronousButton.Enable = str;
            app.MonochromatorCheckBox.Enable = str;
            app.RamanButton.Enable = str;
        end
        
        function setPlot(app)
            set(app.axes1,'FontSize',12);
            set(app.axes1,'FontWeight','bold');
            set(findall(app.axes1, 'Type', 'Line'),'LineWidth',2);
        end
        
        function updateBounds(app)
            % global variables
            global XLimLow; global XLimUpp; global YLimLow; global YLimUpp;
            % check that all bounds are valid
            if(XLimLow ~= XLimUpp && (XLimUpp > XLimLow))
                xlim(app.axes1, [XLimLow XLimUpp]);
            end
            if(YLimLow ~= YLimUpp && (YLimUpp > YLimLow))
                ylim(app.axes1, [YLimLow YLimUpp]);
            end
            setPlot(app);
        end
        
        function updateLog(app, str)
            % global variables
            global WARNINGS;
            WARNINGS = WARNINGS + 1;
            currentLog = app.text7.Text;   % 2D character vector
            row = 1;
            while(size(currentLog,1) >= row)
                if(~isempty(currentLog))
                    if(sum(isspace(currentLog(row,:)))==size(currentLog,2)) % empty
                        currentLog(row,:) = []; % delete
                    end
                end
                row = row + 1;
            end
            % currentLog
            error = sprintf('%s\n%s\n', string(currentLog),string([num2str(WARNINGS) '. ' str]));
            app.text7.Text = error; % update log
        end
        
        function updateSpectra(app)
            % background subtraction
            global backSub; global BackgroundSpectrum; global PlottedSpectra;
            % calibration 
            global isCalibrated; global polyFit; global polyFitRaman;
            % miscellaneous details
            global PlottedSpectraStrs; global MultipleSpectra;
            
            % STAGE 1: Perform background subtraction.
            ModifiedSpectra = nan(size(PlottedSpectra,1),size(PlottedSpectra,2));
            if(backSub)
                for col=1:size(PlottedSpectra,2)    % iterate by column (individual spectrum)
                    for row=1:size(PlottedSpectra,1)
                        ModifiedSpectra(row,col) = PlottedSpectra(row,col)-BackgroundSpectrum(row);
                    end
                end
            else
                ModifiedSpectra = PlottedSpectra;   % just copy elements over
            end
                
            % STAGE 2: Perform plotting based on calibration (fitting).
            % now update plots based on either pixel or wavelength calibration
            switch(isCalibrated)
                case 0
                    domain = 1:2048;    % default pixel calibration
                    xstr = 'Pixel';
                case 1
                    if(app.EmissionButton.Value)    % fluorescence spectrum
                        coeffs = [polyFit(1) polyFit(2) polyFit(3) polyFit(4)];
                        domain = polyval(coeffs,0:2047);   % generate wavelength range over 1 to 2048 pixels
                        xstr = 'Wavelength (nm)';
                    else    % Raman spectrum
                        coeffs = [polyFitRaman(1) polyFitRaman(2) polyFitRaman(3) polyFitRaman(4)];
                        wavelengths = polyval(coeffs,0:2047);    % generate wavelength range over 1 to 2048 pixels
                        % generate corresponding Raman shifts
                        domain = nan(1,length(wavelengths));
                        for i=1:length(wavelengths)
                            domain(i) = 10^7*((1/532)-(1/wavelengths(i)));
                        end
                        xstr = 'Raman Shift (cm^-^1)';
                    end
            end
            set(app.axes1,'NextPlot','replace');    % equivalent to hold off
            % plot updated spectra
            for col=1:size(ModifiedSpectra,2)   % iterate by column (individual spectrum)
                plot(app.axes1,domain,ModifiedSpectra(:,col));
                set(app.axes1,'NextPlot','add');    % equivalent to hold on
            end
            xlabel(app.axes1, xstr);
            ylabel(app.axes1, 'Intensity (a.u.)');
            legend(app.axes1, PlottedSpectraStrs);  % update legend
            % update 'NextPlot' feature
            if(~MultipleSpectra)
                set(app.axes1,'NextPlot','replace');    % equivalent to hold off
            end
            % update plot features
            setPlot(app);
        end
        
        function updateWaitbar(app, PROGRESS, TEXT)
            global BAR; global TAG;
            delete(BAR)     % delete current waitbar object
            delete(TAG);    % delete current text/tag object
            TAG = [];
            WIDTH = 100;    % [%] waitbar width
            BAR = rectangle(app.axes2,'Position',[0,0,(round(1000*PROGRESS/WIDTH))+1,20],'FaceColor','g');
            if(TEXT)        % include text on waitbar during processes
                TAG = text(app.axes2,450,10,[num2str(round(100*PROGRESS/WIDTH)),'%'],'FontWeight','bold');
            end
        end
        
        function ScanEX(app,wavelength)
            % global variables
            global prevWavelength; global referenceLine; global Monochromator;
            global numSteps;
            if(isnan(prevWavelength))   % first time rotating
  		prevWavelength = referenceLine;
            end
            numSteps = (prevWavelength-wavelength)*16;      % consistent with rotation direction
            strSteps = [num2str(numSteps) char(13)];        % command 
            if(numSteps > 0)
                strSteps = ['+' strSteps];    % command
            end
            fprintf(Monochromator,strSteps);
	    app.text6.Text = 'Scanning...';
    	    updateWaitbar(app,0,1);
            pause(0.1)
  	    pauseTime = 0.05*abs(numSteps);   % estimated based on data (motor speed)
	    pause(pauseTime)
   	    updateWaitbar(app,100,1);
 	    pause(0.1)
  	    app.text6.Text = 'Ready.';
	    updateWaitbar(app,0,0);
 	    flushinput(Monochromator);
     	    % update wavelength
	    prevWavelength = wavelength;
        end
        
        function disconnect(~,unit)
            % global variables
            global Spectrometer; global spectrometerDetected; global Monochromator; global motorDetected;
            switch(unit)
                case 'Spectrometer'
                    % disconnect from Spectrometer
                    fclose(Spectrometer);
                    delete(Spectrometer);
                    spectrometerDetected = 0;
                case 'Monochromator'
                    % disconnect from excitation monochromator
                    fclose(Monochromator);
                    delete(Monochromator);
                    motorDetected = 0;
            end
        end
        
        function ComputeEEM(app)
            % global variables
            global EX; global EM; global CorrectedEEM; global ProcessedEEM;
            global WINDOW; global folderPath;
            global INTEGRATIONTIME; global AVERAGE; global StartEX; global FinalEX;
            global RESOLUTION;
            % generate processed EEM (median filtering)
            if(isnan(WINDOW))
                window = 1;
            else
                window = WINDOW;
            end
	    ProcessedEEM = EEMProcessing(app,CorrectedEEM,window);
	    % generate mesh grids
	    [X,Y] = meshgrid(EX,EM);
	    RawZ = CorrectedEEM;	% raw intensities
	    ProcessedZ = ProcessedEEM;	% processed intensities
     
            app.text6.Text = 'Plotting EEM...';
            
            % STAGE 1: Generate 3D Surface Plots.
	    figure
	    surf(X,Y,RawZ);	% raw surface plot
	    % labels
	    xlabel('Excitation Wavelength (nm)');
    	    ylabel('Emission Wavelength (nm)');
    	    zlabel('Intensity (counts)');
	    % font size and weight
    	    set(gca,'FontSize',12);
    	    set(gca,'FontWeight','bold');
    	    colorbar

	    figure
	    surf(X,Y,ProcessedZ);	% processed surface plot
	    % labels
	    xlabel('Excitation Wavelength (nm)');
    	    ylabel('Emission Wavelength (nm)');
    	    zlabel('Intensity (counts)');
	    % font size and weight
    	    set(gca,'FontSize',12);
    	    set(gca,'FontWeight','bold');
    	    colorbar
            
            % STAGE 2: Generate 2D Contour Plots.
	    figure
	    [~, H] = contour(X,Y,RawZ);	% raw contour plot
    	    % labels
    	    xlabel('Excitation Wavelength (nm)');
    	    ylabel('Emission Wavelength (nm)');
    	    zlabel('Intensity (counts)');
    	    % font size and weight
    	    set(gca,'FontSize',12);
    	    set(gca,'FontWeight','bold');
    	    H.LineWidth = 1.5;      % contour line width
    	    colorbar

	    figure
	    [~, H] = contour(X,Y,ProcessedZ);	% processed contour plot
    	    % labels
    	    xlabel('Excitation Wavelength (nm)');
    	    ylabel('Emission Wavelength (nm)');
    	    zlabel('Intensity (counts)');
    	    % font size and weight
    	    set(gca,'FontSize',12);
    	    set(gca,'FontWeight','bold');
    	    H.LineWidth = 1.5;      % contour line width
    	    colorbar
            
            % now write processed EEM to text file
            % generate full datapath
            details = cellstr(datetime);
            dateTime = details{1}; 
            spaceIndex = strfind(dateTime,' '); % find space
            currentDate = dateTime(1:spaceIndex);   % date string  
            currentTime = dateTime(spaceIndex+1:length(dateTime));  % time string
            colons = strfind(currentTime,':');  % return 2 colons
            currentTime(colons(1)) = '-';
            currentTime(colons(2)) = '-';
            strPath = ['Processed EEM' ' ' currentDate currentTime '.xlsx'];
            if(~isempty(folderPath))
                toWrite = fullfile(folderPath,strPath);
            else
                toWrite = strPath;
            end
            % write processed EEM
            xlswrite(toWrite,cellstr('Wavelength (nm)'),'Sheet1','A1');
            xlswrite(toWrite,EM','Sheet1','A2');                       % write wavelengths
            % generate excitation vector strings
            EXStrings = cell(1,length(EX));
            for i=1:length(EX)
                EXStrings{i} = ['EX = ' num2str(EX(i)) ' nm'];
            end
            xlswrite(toWrite,EXStrings,'Sheet1','B1');      % write excitation vector
            xlswrite(toWrite,ProcessedEEM,'Sheet1','B2');            % write spectra
            % add tags
            str = ['I = ' num2str(INTEGRATIONTIME) ' ms, A = ' num2str(AVERAGE)];
            EXTag = ['EX [nm]: ' num2str(StartEX) '-' num2str(FinalEX)];
            ResolutionTag = ['RESOLUTION [nm]: ' num2str(RESOLUTION)];
            WindowTag = ['Median Width: ' num2str(window) ' ' 'points.'];
            xlswrite(toWrite,cellstr(str),'Sheet2','A1');
            xlswrite(toWrite,cellstr(EXTag),'Sheet2','A2');
            xlswrite(toWrite,cellstr(ResolutionTag),'Sheet2','A3');
            xlswrite(toWrite,cellstr(WindowTag),'Sheet2','A4');
            % synchronous spectra
            pause(0.1)
            WriteSynchronousSpectra(app);
        end
        
        function [ProcessedEEM] = EEMProcessing(app,RawEEM,WINDOW)
            % global variables
            global EM;
            % performs median filtering and zero correction on original EEM
            ProcessedEEM = nan(size(RawEEM,1),size(RawEEM,2));
            FIRSTORDERCUTOFF = 400;     % cutoff wavelength for first order region [nm] 
            firstOrderBound = findClosestValue(app,EM,FIRSTORDERCUTOFF); % for zero correction
    
            for col=1:size(RawEEM,2)
                % (1) correct for spikes using median filter with input window width
                originalSpectrum = RawEEM(:,col);
                newSpectrum = medfilt1(originalSpectrum, WINDOW);
                
                % (2) perform zero correction (noise) within first order region only
                newSpectrum2 = nan(length(originalSpectrum),1);
                min = 0;
                for j=1:firstOrderBound
                    if(newSpectrum(j) < min)
                        min = newSpectrum(j);
                    end
                end
                CORRECTION = -1*min;   % correction offset
                for k=1:length(newSpectrum)
                    newSpectrum2(k) = newSpectrum(k)+CORRECTION;
                end

            ProcessedEEM(:,col) = newSpectrum2;
            end
        end
        
        function [index] = findClosestValue(~, mat,value)
            % emission wavelengths do not necessarily strictly satisfy ROI; we thus find closest point which does
            DIFFERENCE = inf;
            if(value < mat(1))  % when lower bound is below first wavelength
                index = 1;
            else
                % find closest emission wavelength which satisfies ROI constraints
                for i=1:length(mat)
                    if(abs(mat(i)-value) < DIFFERENCE)
                        index = i;
                        DIFFERENCE = abs(mat(i)-value);
                    end
                end
            end 
        end
        
        function WriteSynchronousSpectra(app)
            % global variables
            global folderPath; global EX; global EM; global CorrectedEEM; global ProcessedEEM;
            % for synchronous scans
            RawSpectrum = [];
            ProcessedSpectrum = [];
            Wavelengths = [];
            Tags = {};
            AllRawData = [];
            AllCorrectedData = [];
            deltaLambda = 5:5:100;  % [nm] range of relevant delta-lambdas
            
            app.text6.Text = 'Writing Synchronous Spectra...';
            
            % generate full datapath
            details = cellstr(datetime);
            dateTime = details{1}; 
            spaceIndex = strfind(dateTime,' '); % find space
            currentDate = dateTime(1:spaceIndex);   % date string  
            currentTime = dateTime(spaceIndex+1:length(dateTime));  % time string
            colons = strfind(currentTime,':');  % return 2 colons
            currentTime(colons(1)) = '-';
            currentTime(colons(2)) = '-';
            strPath = ['Synchronous Spectra' ' ' currentDate currentTime '.xlsx'];
            if(~isempty(folderPath))
                toWrite = fullfile(folderPath,strPath);
            else
                toWrite = strPath;
            end
            % write all synchronous spectra
            for i=1:length(deltaLambda)
                % raw EEM
                [RawSpectrum, ~] = ExtractSynchronousSpectrum(app,deltaLambda(i),EX,EM,CorrectedEEM);
                [ProcessedSpectrum, Wavelengths] = ExtractSynchronousSpectrum(app,deltaLambda(i),EX,EM,ProcessedEEM);
                RawSpectrum = [RawSpectrum nan(1,1000-length(RawSpectrum))];    % pad
                ProcessedSpectrum = [ProcessedSpectrum nan(1,1000-length(ProcessedSpectrum))];  % pad
                Wavelengths = [Wavelengths nan(1,1000-length(Wavelengths))];    % pad
                AllRawData = [AllRawData Wavelengths' RawSpectrum'];
                AllCorrectedData = [AllCorrectedData Wavelengths' ProcessedSpectrum'];
                Tags = [Tags cellstr('Wavelength (nm)') cellstr([num2str(deltaLambda(i)) ' nm'])];
            end
            % Tags
            % write raw data
            xlswrite(toWrite,Tags,'Sheet1','A1');
            xlswrite(toWrite,AllRawData,'Sheet1','A2');
            % write processed data
            xlswrite(toWrite,Tags,'Sheet2','A1');
            xlswrite(toWrite,AllCorrectedData,'Sheet2','A2');
            
            % reset
            app.text6.Text = 'Ready.';
        end
        
        function [Spectrum, Wavelengths] = ExtractSynchronousSpectrum(app,deltaLambda,EX,EM,intensities)
            % for synchronous scans
            Spectrum = [];      % for plotting synchronous spectra
            Wavelengths = [];   % for plotting synchronous spectra 
            for i=1:length(EX)
                value = EX(i)+deltaLambda;
                indexToExtract = findClosestValue(app,EM,value);   % find closest value which satisfies delta lambda
                if(indexToExtract == -1)        % wait until emission range overlaps with synchronous range
                    Wavelengths = [];
                    Spectrum = [];
                else
                    Wavelengths = [Wavelengths EM(indexToExtract)];
                    Spectrum = [Spectrum intensities(indexToExtract,i)];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SpectrumAcquisition_OpeningFcn(app)
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SpectrumAcquisition (see VARARGIN)
            
            % INITIALIZATION OF PORTS AND FLUSHING BUFFER
            % populate Ports List with list of available Serial (communication) ports
            global serialList;
            % flush all ports
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            serialList = seriallist;    % get ports
            app.listbox1.Items = serialList;    % update Serial list
            % modify uitable1
            app.uitable1.Data =  cell(10,2);
            % create visual waitbar
            waitbarAx = app.axes2;
            set(waitbarAx,'Xtick',[],'Ytick',[],'Xlim',[0 1000]);
            updateWaitbar(app,0,0);
        end

        % Value changed function: checkbox4
        function checkbox4_Callback(app, event)
            % global variables
            global spectrometerDetected; global isContinuous;
            if(~spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
                isContinuous = 0;
            elseif(app.checkbox4.Value)
                isContinuous = 1;    	% set bit HIGH
            else
                isContinuous = 0;       % set bit LOW
            end
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % global variables
            global isCalibrated; global spectrometerDetected; global polyFit; global PlottedSpectra;
            isCalibrated = app.checkbox1.Value;
            % first check if  spectrometer is connected
            if(~spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
                isCalibrated = 0;
            elseif(isempty(polyFit))    % check if fit has been generated yet
                updateLog(app, 'NO FITTING GENERATED.');
                isCalibrated = 0;
            else
                % update plots
                if(~isempty(PlottedSpectra))    % check if any spectra have been acquired
                    updateSpectra(app);
                end
            end
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
            % global variables
            global backSub; global BackgroundSpectrum;
            backSub = app.checkbox2.Value;
            % check to see if background exists
            if(isempty(BackgroundSpectrum) && backSub)
                updateLog(app,'NO BACKGROUND SET.');
                backSub = 0;
            else
                updateSpectra(app);
            end
        end

        % Value changed function: checkbox3
        function checkbox3_Callback(app, event)
            % global variables
            global MultipleSpectra;
            if(app.checkbox3.Value)
                MultipleSpectra = 1;    % set bit HIGH
                app.axes1.NextPlot = 'add';  % equivalent to hold on
            else
                MultipleSpectra = 0;
                app.axes1.NextPlot = 'replace'; % equivalent to hold off
            end
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % global variables
            global XLimLow;
            XLimLow = str2double(app.edit1.Value);
            if(isnan(XLimLow))
                updateLog(app,'INVALID LIMIT.');
            end
            updateBounds(app);
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % global variables
            global YLimLow;
            YLimLow = str2double(app.edit3.Value);
            if(isnan(YLimLow))
                updateLog(app,'INVALID LIMIT.');
            end
            updateBounds(app);
        end

        % Value changed function: edit4
        function edit4_Callback(app, event)
            % global variables
            global YLimUpp;
            YLimUpp = str2double(app.edit4.Value);
            if(isnan(YLimUpp))
                updateLog(app,'INVALID LIMIT.');
            end
            updateBounds(app);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % global variables
            global XLimUpp;
            XLimUpp = str2double(app.edit2.Value);
            if(isnan(XLimUpp))
                updateLog(app,'INVALID LIMIT.');
            end
            updateBounds(app);
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            % global variables
            global AVERAGE;
            AVERAGE = str2double(app.edit8.Value);
            if(AVERAGE < 1 || isnan(AVERAGE))       % errors
                updateLog(app,'INVALID AVERAGE.');
                AVERAGE = 1;    % default average value
            end
            app.edit8.Value = num2str(AVERAGE);
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            % global variables
            global INTEGRATIONTIME;
            INTEGRATIONTIME = str2double(app.edit7.Value);
            if(INTEGRATIONTIME < 50 || INTEGRATIONTIME > 65500 || isnan(INTEGRATIONTIME))
                updateLog(app,'INVALID INTEGRATION TIME.');
                INTEGRATIONTIME = 50;   % [ms] default integration time
            end
            app.edit7.Value = num2str(INTEGRATIONTIME);
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            % global variables
            global StartEX; global referenceLine; global motorDetected;
            TempStartEX = str2double(app.edit5.Value);
            if(TempStartEX < 250 || TempStartEX > 800 || isnan(TempStartEX))    % current range --> arbitrary
                StartEX = nan;   % [ms] default integration time
                app.edit5.Value = 'Start EX';
            elseif(isnan(referenceLine))
                updateLog(app,'INVALID REFERENCE LINE.');
                StartEX = nan;
                app.edit5.Value = 'Start EX';
            elseif(~motorDetected)
                updateLog(app,'NO MOTOR DETECTED.');
                StartEX = nan;
                app.edit5.Value = 'Start EX';
            else
                % store initial excitation wavelength
                StartEX = TempStartEX;
            end
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            % global variables
            global StartEX; global FinalEX; global referenceLine; global motorDetected;
            TempFinalEX = str2double(app.edit6.Value);
            if(TempFinalEX < 250 || TempFinalEX > 800 || TempFinalEX < StartEX || isnan(TempFinalEX))    
                FinalEX = nan;   % [ms] default integration time
                app.edit6.Value = 'Final EX';
            elseif(isnan(referenceLine))
                updateLog(app,'INVALID REFERENCE LINE.');
                FinalEX = nan;
                app.edit6.Value = 'Final EX';
            elseif(~motorDetected)
                updateLog(app,'NO MOTOR DETECTED.');
                FinalEX = nan;
                app.edit6.Value = 'Final EX';
            else
                % store final excitation wavelength
                FinalEX = TempFinalEX;
            end
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % global variables
            global serialPortSelected;
            serialPortSelected = app.listbox1.Value;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % global variables
            % spectrometer
            global AcquiredSpectrum; global AveragedSpectrum; global BackgroundSpectrum;
            global AVERAGE; global INTEGRATIONTIME; global spectrometerDetected; global Spectrometer;
            global EX; global EM; global EEM; global BackgroundEEM; global CorrectedEEM; 
            % excitation monochromator
            global Monochromator; global motorDetected; global StartEX; global FinalEX;
            global referenceLine; global prevWavelength; global RESOLUTION;
            % plotting
            global PlottedSpectra; global PlottedSpectraStrs; global MultipleSpectra; global isContinuous;
            global endAcquisition; global folderPath; global polyFit; global polyFitRaman; global WINDOW;
            % saving
            global saveSpectrum; global spectraWritten;
            % first check if spectrometer is detected
            if(~spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
            else
                % median filter not selected
                if(~app.MedianFilterCheckBox.Value)
                    WINDOW = nan;   % set to nan
                end
                % Case 1: Synchronous Spectrum (EEM)
                if(app.SynchronousButton.Value)
                    % error checking
                    if(isnan(referenceLine))
                        updateLog(app,'INVALID REFERENCE LINE.');
                    elseif(isnan(StartEX) || isnan(FinalEX))
                        updateLog(app,'INVALID WAVELENGTH(S).');
                    elseif(isnan(RESOLUTION))
                        updateLog(app,'INVALID RESOLUTION.');
                    else
                        % STAGE 1: Rotate to Starting EX wavelength.
                        ScanEX(app,StartEX);
                        
                        % STAGE 2: Preliminary scanning details.
                        % clear and populate matrices for each new EEM scan
                        EX = StartEX:RESOLUTION:FinalEX;    % excitation vector [nm]
                        numSpec = length(EX);   % total number of spectra in EEM
                        EEM = nan(2048,numSpec);    % 3-D EEM
                        BackgroundEEM = nan(2048,numSpec);  % background EEM
                        if(~isempty(BackgroundSpectrum))
                            for i=1:size(BackgroundEEM,2)
                                BackgroundEEM(:,i) = BackgroundSpectrum;  % populate background EEM
                            end
                        end
                        
                        % STAGE 3: Start Acquiring Spectra
                        for i=0:numSpec-1
                            currentEX = StartEX+RESOLUTION*(i);
                            app.text6.Text = ['EX = ' num2str(currentEX) ' nm...'];
                            % acquire spectrum
                            fprintf(Spectrometer,['I' num2str(INTEGRATIONTIME)]);
                            % receiving message
                            for j=1:2
                                fscanf(Spectrometer);
                            end
                            flushinput(Spectrometer);
                            % transmitting message (initiate scan)
                            fprintf(Spectrometer,'S');
                            % receiving message (acquire data)
                            fscanf(Spectrometer); fscanf(Spectrometer);
                            pause(max((INTEGRATIONTIME+1000)/1000,1))   % wait sufficient time [s] for buffer to fill
                            AcquiredSpectrum = nan(2048,1);
                            for k=1:2048 % 2048 pixels' worth of ASCII data returned
                                AcquiredSpectrum(k) = str2double(fscanf(Spectrometer));
                            end
                            pause(0.1)
                            % flush buffer
                            flushinput(Spectrometer);
                            EEM(:,i+1) = AcquiredSpectrum;
                            strRotation = ['-' num2str(RESOLUTION*16)];
                            if(i ~= numSpec-1)
                                fprintf(Monochromator,strRotation);	
                            end
                            % update current wavelength
                            prevWavelength = prevWavelength + RESOLUTION;
                            % compute pause time based on motor
                            % specifications
                            pauseTime = RESOLUTION*16*(1/20);   % use resolution and steps per second (SPS)
			    pause(pauseTime+1)   % wait extra second
                        end
                        
                        % STAGE 3: Write EEM to file.
                        app.text6.Text = 'Writing EEM...';
                        updateWaitbar(app,0,1)
                        str = ['I = ' num2str(INTEGRATIONTIME) ' ms, A = ' num2str(AVERAGE)];
                        EXTag = ['EX [nm]: ' num2str(StartEX) '-' num2str(FinalEX)];
                        ResolutionTag = ['RESOLUTION [nm]: ' num2str(RESOLUTION)];
                        if(~isempty(polyFit))
                            WAVELENGTHS = polyval(polyFit,0:2047);
                        else
                            WAVELENGTHS = 1:2048;
                        end
                        EM = WAVELENGTHS;
                        % generate full datapath
                        updateWaitbar(app,25,1);
                        details = cellstr(datetime);    % fetch current date and time
                        dateTime = details{1};
                        spaceIndex = strfind(dateTime, ' ');  % find space
                        currentDate = dateTime(1:spaceIndex);   % date string
                        currentTime = dateTime(spaceIndex+1:length(dateTime));  % time string
                        colons = strfind(currentTime,':');  % return 2 colons
                        currentTime(colons(1)) = '-';
                        currentTime(colons(2)) = '-';
                        strPath = ['EEM' ' ' currentDate currentTime '.xlsx'];
                        if(~isempty(folderPath))
                            toWrite = fullfile(folderPath,strPath);
                        else
                            toWrite = strPath;
                        end
                        xlswrite(toWrite,cellstr('Wavelength (nm)'),'Sheet1','A1');
                        xlswrite(toWrite,WAVELENGTHS','Sheet1','A2');                       % write wavelengths
                        % generate excitation vector strings
                        updateWaitbar(app,50,1);
                        EXStrings = cell(1,length(EX));
                        for i=1:length(EX)
                            EXStrings{i} = ['EX = ' num2str(EX(i)) ' nm'];
                        end
                        xlswrite(toWrite,EXStrings,'Sheet1','B1');      % write excitation vector
                        xlswrite(toWrite,EEM,'Sheet1','B2');            % write spectra
                        updateWaitbar(app,75,1);
                        if(~isempty(BackgroundSpectrum))
                            xlswrite(toWrite,cellstr('Wavelength (nm)'),'Sheet2','A1');
                            xlswrite(toWrite,WAVELENGTHS','Sheet2','A2');	% write wavelengths
                            xlswrite(toWrite,EXStrings,'Sheet2','B1');
                            CorrectedEEM = EEM-BackgroundEEM;		% correctedEEM
                            xlswrite(toWrite,CorrectedEEM,'Sheet2','B2');  	% write corrected EEM 
                            % add tag
                            xlswrite(toWrite,cellstr(str),'Sheet3','A1');       % describes EEM
		            xlswrite(toWrite,cellstr(EXTag),'Sheet3','A2');	
                            xlswrite(toWrite,cellstr(ResolutionTag),'Sheet3','A3');
                        else
                            % add tag
                            CorrectedEEM = EEM;
                            xlswrite(toWrite,cellstr(str),'Sheet2','A1');       % describes EEM
		            xlswrite(toWrite,cellstr(EXTag),'Sheet2','A2');	
                            xlswrite(toWrite,cellstr(ResolutionTag),'Sheet2','A3');
                        end
                        updateWaitbar(app,100,1);
                        pause(0.1)
                        updateWaitbar(app,0,0);
                        app.text6.Text = 'Ready.';
                    end
                    % STAGE 4: Compute EEM and extract synchronous spectra.
                    ComputeEEM(app);
                % Case 2: Emission or Raman Spectrum
                else   
                    % check that motor is detected AND monochromator is
                    % selected as excitation source
                    if(motorDetected && app.MonochromatorCheckBox.Value)
                        % set excitation grating
                        ScanEX(app,StartEX);
                    end
                    counterSpectra = 0;
                    % disable excitation monochromator controls
                    disable(app,0);
                    while((isContinuous || ~counterSpectra) && (~endAcquisition))
                        % STAGE 0: Preliminary details.
                        % check whether there are multiple spectra or not
                        if(~MultipleSpectra)
                            PlottedSpectra = [];        % actual spectra
                            PlottedSpectraStrs = {};    % clear spectral lists (string identifiers)
                        end
            
                        % update legend details first to avoid any issues arising from
                        % text field changes during acquisition process itself
                        str = ['I = ' num2str(INTEGRATIONTIME) ' ms, A = ' num2str(AVERAGE)];
                        PlottedSpectraStrs = [PlottedSpectraStrs cellstr(str)];
            
                        % reset flag bit
                        endAcquisition = 0;
                
                        % STAGE 1: Passing parameters and checking communication with spectrometer.
            
                        % SETTING INTEGRATION TIME
                        % transmitting message
                        fprintf(Spectrometer,['I' num2str(INTEGRATIONTIME)]);
                        % receiving message
                        for i=1:2
                            fscanf(Spectrometer);
                        end
                        % now query spectrometer for integration time
                        success = CommunicationCheck(app,'I');
                        if(~success)    % communication failure
                            updateLog(app,'COMMUNICATION FAILURE.');
                            spectrometerDetected = 0;   % disconnect
                            disconnect(app,'Spectrometer');
                        else
                            % SETTING AVERAGE in SOFTWARE, not HARDWARE
                            % (otherwise longer wait for user)
                            AveragedSpectrum = [];
                            for i=1:AVERAGE
                                if(~endAcquisition)     % check if scan is canceled at any point during acquisition
                                    % STAGE 2: Acquire spectrum.
                                    strText = ['Acquiring Spectrum... ' num2str(i) '.'];
                                    app.text6.Text = strText;
                                    updateWaitbar(app,0,1);
                                    % transmitting message (initiate scan)
                                    fprintf(Spectrometer,'S');
                                    % receiving message (acquire data)
                                    fscanf(Spectrometer); fscanf(Spectrometer);
                                    try
                                        updateWaitbar(app,25,1);
                                        pause(max((INTEGRATIONTIME+1000)/1000, 1));  % wait sufficient time [s] for buffer to fill
                                        for j=1:2048    % 2048 pixels of ASCII data returned
                                            AcquiredSpectrum(j) = str2double(fscanf(Spectrometer));
                                        end
                                        updateWaitbar(app,50,1);
                                        pause(0.1)
                                        % check for median filter
                                        if(isnan(WINDOW))
                                            % averaging
                                            AveragedSpectrum(i,:) = AcquiredSpectrum;
                                        else
                                            % averaging
                                            AveragedSpectrum(i,:) = medfilt1(AcquiredSpectrum,WINDOW);
                                        end
                                        updateWaitbar(app,75,1);
                                        pause(0.1)
                                        % flush buffer
                                        flushinput(Spectrometer);
                                    catch   % failure during final buffer flush
                                        updateLog(app,'COMMUNICATION FAILURE.');
                                        spectrometerDetected = 0;   % disconnect spectrometer
                                        disconnect(app,'Spectrometer');
                                        % empty data matrices
                                        AcquiredSpectrum = [];
                                        AveragedSpectrum = [];
                                    end
            
                                    % STAGE 3: Plot spectrum.
                                    if(~isempty(AcquiredSpectrum))  % guaranteed to be non-empty during successful operation
                                        updateWaitbar(app,100,1);
                                        pause(0.1)
                                        % push either the acquired or averaged spectrum onto matrix
                                        if(AVERAGE == 1 || i == 1)  % single spectrum OR initial spectrum in averaging
                                            PlottedSpectra = [PlottedSpectra AveragedSpectrum'];    % 2-dimensional
                                        else                        % averaged spectrum (in progress or complete)
                                            PlottedSpectra(:,size(PlottedSpectra,2)) = mean(AveragedSpectrum)'; % otherwise, just update last spectrum
                                        end
                                        updateSpectra(app);
                                    end
                                end
                            end
            
                            % STAGE 4: Final details.
                            counterSpectra = counterSpectra + 1;
                            updateWaitbar(app,0,0);
                            app.text6.Text = 'Ready.';
                            pause(0.1)
                        
                            % STAGE 5: for saving
                            if(saveSpectrum)
                                % generate appropriate wavelengths or Raman shifts vector
                                if(~isempty(polyFit))
                                    if(app.EmissionButton.Value) % fluorescence 
                                        WAVELENGTHS = polyval(polyFit,0:2047);
                                        DOMAIN = WAVELENGTHS;
                                        xstr = 'Wavelength (nm)';
                                    else % Raman
                                        WAVELENGTHS = polyval(polyFitRaman,0:2047);
                                        % generate corresponding Raman shifts
                                        RamanShifts = nan(1,length(WAVELENGTHS));
                                        for i=1:length(WAVELENGTHS)
                                            RamanShifts(i) = 10^7*((1/532)-(1/WAVELENGTHS(i)));
                                        end
                                        DOMAIN = RamanShifts;
                                        xstr = 'Raman Shift (cm^-^1)';
                                    end
                                else
                                    DOMAIN = 1:2048;
                                    xstr = 'Pixel';
                                end
                                % generate full datapath
                                app.text6.Text = 'Writing...';
                                updateWaitbar(app,0,1);
                                details = cellstr(datetime);    % fetch current date and time
                                dateTime = details{1};
                                spaceIndex = strfind(dateTime, ' ');  % find space
                                currentDate = dateTime(1:spaceIndex);   % date string
                                currentTime = dateTime(spaceIndex+1:length(dateTime));  % time string
                                colons = strfind(currentTime,':');  % return 2 colons
                                currentTime(colons(1)) = '-';
                                currentTime(colons(2)) = '-';
                                strPath = ['Spectrum' num2str(spectraWritten+1) ' ' currentDate currentTime '.xlsx'];
                                if(~isempty(folderPath))
                                    toWrite = fullfile(folderPath,strPath);
                                else
                                    toWrite = strPath;
                                end
                                xlswrite(toWrite,cellstr(xstr),'Sheet1','A1');
                                xlswrite(toWrite,DOMAIN','Sheet1','A2');                       % write wavelengths
                                xlswrite(toWrite,cellstr('Intensity (a.u.)'),'Sheet1','B1');
                                xlswrite(toWrite,PlottedSpectra(:,size(PlottedSpectra,2)),'Sheet1','B2');            % write spectra
                                updateWaitbar(app,50,1);
                                if(~isempty(BackgroundSpectrum))
                                    xlswrite(toWrite,cellstr('Background Intensity (a.u.)'),'Sheet1','C1');
                                    xlswrite(toWrite,BackgroundSpectrum,'Sheet1','C2');            % write background spectrum
                                    xlswrite(toWrite,cellstr('Corrected Intensity (a.u.)'),'Sheet1','D1');
                                    CorrectedSpectrum = PlottedSpectra(:,size(PlottedSpectra,2))-BackgroundSpectrum;
                                    xlswrite(toWrite,CorrectedSpectrum,'Sheet1','D2');              % write corrected spectrum
                                end
                                % add tag
                                xlswrite(toWrite,cellstr(str),'Sheet1','E1');       % describes spectrum
                                spectraWritten = spectraWritten + 1;
                                updateWaitbar(app,100,1);
                                pause(0.1)
                                updateWaitbar(app,0,0);
                                app.text6.Text = 'Ready.';
                            end
                        end
                    end
                    % reset flag bit
                    endAcquisition = 0;
                    % enable excitation monochromator controls
                    disable(app,1);
                end
            end
        end

        % Callback function
        function pushbutton2_Callback(app, event)
            % global variables
            % background subtraction
            global backSub; global BackgroundSpectrum; global PlottedSpectra;
            % calibration 
            global isCalibrated; global polyFit;
            % miscellaneous details
            global PlottedSpectraStrs;
            
            % generate new, undocked figure
            figure
            
            % STAGE 1: Perform background subtraction.
            ModifiedSpectra = nan(size(PlottedSpectra,1),size(PlottedSpectra,2));
            if(backSub)
                for col=1:size(PlottedSpectra,2)    % iterate by column (individual spectrum)
                    for row=1:size(PlottedSpectra,1)
                        ModifiedSpectra(row,col) = PlottedSpectra(row,col)-BackgroundSpectrum(row);
                    end
                end
            else
                ModifiedSpectra = PlottedSpectra;   % just copy elements over
            end
                
            % STAGE 2: Perform plotting based on calibration (fitting).
            % now update plots based on either pixel or wavelength calibration
            switch(isCalibrated)
                case 0
                    domain = 1:2048;    % default pixel calibration
                    xstr = 'Pixel';
                case 1
                    coeffs = [polyFit(2) polyFit(3) polyFit(4)];
                    domain = polyval(coeffs,0:2047);   % generate wavelength range over 1 to 2048 pixels
                    xstr = 'Wavelength (nm)';
            end
            
            % plot updated spectra
            for col=1:size(ModifiedSpectra,2)   % iterate by column (individual spectrum)
                plot(gca,domain,ModifiedSpectra(:,col));
                set(gca,'NextPlot','add');    % equivalent to hold on
            end
            xlabel(gca,xstr);
            ylabel(gca,'Intensity (a.u.)');
            legend(gca,PlottedSpectraStrs);  % update legend
            % update 'NextPlot' feature
            % update plot features
            setPlot(app);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % global variables
            global spectrometerDetected; global polyFit; global calibratedPixels; global calibratedWavelengths;
            % first check if spectrometer is detected
            if(spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
            else
                app.text6.Text = 'Calibrating...';
                updateWaitbar(app,0,1);
                pause(0.1)
                % fetch data
                calibrationData = app.uitable1.Data;
                calibratedPixels = nan(1,size(calibrationData,1));
                calibratedWavelengths = nan(1,size(calibrationData,1));
                % fetch pixels (x)
                for i=1:size(calibrationData,1)
                    if(~isnan(calibrationData{i,1}))
                        calibratedPixels(i) = str2double(string(calibrationData(i,1)));
                    end
                end
                updateWaitbar(app,50,1);
                pause(0.1)
                % fetch wavelengths (y)
                for j=1:size(calibrationData,1)
                    if(~isnan(calibrationData{j,2}))
                        calibratedWavelengths(j) = str2double(string(calibrationData(j,2)));
                    end
                end
                % remove NaNs
                calibratedPixels = calibratedPixels(~isnan(calibratedPixels));
                calibratedWavelengths = calibratedWavelengths(~isnan(calibratedWavelengths));
                % generate polynomial fitting of DEGREE n + 1 --> 3 + 1 = 4
                polyFit = polyfit(calibratedPixels,calibratedWavelengths,3);
                % then, update uitable1 with fitting coefficients
                tableData = app.uitable1.Data;
                fittedColumn = cell(size(calibrationData,1),1);
                fittedColumn(1:4) = num2cell(polyFit);
                % switch based on whether fit exists or not
                switch(size(tableData,2))
                    case 2  % no fit exists
                        app.uitable1.Data = [tableData(:,1:size(tableData,2)) fittedColumn];        % update table
                    case 3  % fit already exists, so overwrite it
                        app.uitable1.Data = [tableData(:,1:size(tableData,2)-1) fittedColumn];      % update table
                end
                updateWaitbar(app,100,1);
                pause(0.1)
                updateWaitbar(app,0,0);
                app.text6.Text = 'Ready.';
            end
        end

        % Callback function
        function pushbutton4_Callback(app, event)
            % global variables
            global BackgroundSpectrum; global spectrometerDetected;
            % first check if spectrometer is connected
            if(~spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
            elseif(isempty(BackgroundSpectrum)) % now check to see if any background exists
                updateLog(app,'NO BACKGROUND SET.');
            else
                app.text6.Text = 'Clearing Background...';
                updateWaitbar(app,0,1);
                pause(0.1)
                BackgroundSpectrum = [];  % clear
                updateWaitbar(app,100,1);
                pause(0.1)
                updateWaitbar(app,0,0);
                app.text6.Text = 'Ready.';
            end
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % global variables
            global serialList;
            serialList = seriallist;
            app.listbox1.Items = serialList;
            app.text6.Text = 'Ready.';
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            % global variables
            global serialList; global serialPortSelected; global Spectrometer;
            global spectrometerDetected;
            if(isempty(serialList))
                updateLog(app,'NO PORTS DETECTED.');
            elseif(spectrometerDetected)
                updateLog(app,'SPECTROMETER DETECTED.');
            else
                % first detect and connect to spectrometer
                try
                    app.text6.Text = 'Detecting...';
                    updateWaitbar(app,0,1);
                    pause(0.1)
                    Spectrometer = serial(serialPortSelected{1});
                    updateWaitbar(app,50,1);
                    pause(0.1)
                    % set characteristics before opening serial connection
                    set(Spectrometer,'Timeout',60); % --> arbitrary timeout (1 minute)
                    set(Spectrometer,'InputBufferSize',50000);  % --> arbitrary size
                    % open serial port connection
                    fopen(Spectrometer);
                    updateWaitbar(app,100,1);
                    pause(0.1)
                    spectrometerDetected = 1;
                    % set default communication mode to ASCII
                    fprintf(Spectrometer,'a');
                    pause(1);
                    flushinput(Spectrometer);
                    fprintf(Spectrometer,'K0');
                    pause(1)
                    flushinput(Spectrometer);
                    % then, close communication channel and increase baud rate
                    fclose(Spectrometer);
                    set(Spectrometer,'BaudRate',115200);    % highest possible baud rate
                    fopen(Spectrometer);                    % reopen channel
                    % set default communication mode to ASCII
                    fprintf(Spectrometer,'a');
                    pause(1);
                    flushinput(Spectrometer);
                    % now query spectrometer for baud rate
                    success = CommunicationCheck(app,'K');
                    if(~success)    % communication failure
                        updateLog(app,'COMMUNICATION FAILURE.');
                        spectrometerDetected = 0;   % disconnect
                    end
                catch
                    app.text6.Text = 'Ready.';
                    updateWaitbar(app,0,0);
                    updateLog(app,'NO SPECTROMETER DETECTED.');
                    spectrometerDetected = 0;
                end
                % default spectrometer communication mode is ASCII
                app.text6.Text = 'Ready.';
                updateWaitbar(app,0,0);
            end
        end

        % Button pushed function: pushbutton8
        function pushbutton8_Callback(app, event)
            % global variables
            global BackgroundSpectrum; global PlottedSpectra; global spectrometerDetected;
            % first check if spectrometer is connected
            if(~spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
            elseif(isempty(PlottedSpectra)) % now check to see if any spectra are plotted
                updateLog(app,'NO SPECTRUM ACQUIRED.');
            else
                BackgroundSpectrum = PlottedSpectra(:,size(PlottedSpectra,2));  % define background as the last (most recent) spectrum acquired
            end
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % global variables
            global spectrometerDetected; global polyFit; global calibratedPixels; global calibratedWavelengths;
            % first check if spectrometer is detected
            if(spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
            elseif(isempty(polyFit))    % now check if fitting has been generated
                updateLog(app,'NO FITTING GENERATED.');
            else
                [fileWritten, pathWritten] = uiputfile;
                toWrite = [pathWritten fileWritten];    % generate full datapath
                try
                    [existingData, existingSpectra] = xlsread(toWrite);     % check if any data or spectra are being overwritten
                    if(~isempty(existingData) || ~isempty(existingSpectra))
                        updateLog(app,'SELECTED FILE OVERWRITTEN.');    % generate warning
                    end
                catch
                    xlswrite(toWrite,cellstr('Pixel'),'Sheet1','A1');
                    app.text6.Text = 'Writing...';
                    updateWaitbar(app,0,1);
                    pause(0.1);
                    xlswrite(toWrite,calibratedPixels','Sheet1','A2');          % write pixels
                    updateWaitbar(app,25,1);
                    pause(0.1);
                    xlswrite(toWrite,cellstr('Wavelength'),'Sheet1','B1');
                    updateWaitbar(app,50,1);
                    pause(0.1);
                    xlswrite(toWrite,calibratedWavelengths','Sheet1','B2');     % write wavelengths
                    updateWaitbar(app,75,1);
                    pause(0.1);
                    xlswrite(toWrite,cellstr('Fit'),'Sheet1','C1');
                    updateWaitbar(app,100,1);
                    pause(0.1);
                    xlswrite(toWrite,polyFit','Sheet1','C2');                   % write polynomial fitting coefficients
                    app.text6.Text = 'Ready.';
                    updateWaitbar(app,0,0);
                end
            end
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % global variables
            global polyFit; global calibratedPixels; global calibratedWavelengths;
            app.text6.Text = 'Loading...';
            updateWaitbar(app,0,1);
            pause(0.1);
            % fetch file
            filename = uigetfile;
            % read data
            dataToLoad = xlsread(filename);
            updateWaitbar(app,50,1);
            pause(0.1);
            % extract and remove NaNs from file matrices
            pixelsToLoad = dataToLoad(:,1);         % pixels
            pixelsToLoad = pixelsToLoad(~isnan(pixelsToLoad));
            wavelengthsToLoad = dataToLoad(:,2);    % wavelengths
            wavelengthsToLoad = wavelengthsToLoad(~isnan(wavelengthsToLoad));
            fitToLoad = dataToLoad(:,3);            % fitting coefficients
            fitToLoad = fitToLoad(~isnan(fitToLoad));
            % set table matrices
            calibratedPixels = cell(10,1);          % set width to 10
            calibratedWavelengths = cell(10,1);
            polyFit = cell(10,1);
            % populate table matrices
            calibratedPixels(1:length(pixelsToLoad)) = num2cell(pixelsToLoad);
            calibratedWavelengths(1:length(wavelengthsToLoad)) = num2cell(wavelengthsToLoad);
            polyFit(1:length(fitToLoad)) = num2cell(fitToLoad);
            % update table
            updateWaitbar(app,100,1);
            pause(0.1);
            app.uitable1.Data = [calibratedPixels calibratedWavelengths polyFit];
            app.text6.Text = 'Ready.';
            updateWaitbar(app,0,0);
        end

        % Button pushed function: pushbutton13
        function pushbutton13_Callback(app, event)
            % global variables
            global folderPath; 
            % generate folder path
            folderPath = uigetdir();
            parts = regexp(folderPath,'\','split'); 
            folderStr = ['Folder: ' parts{length(parts)}];
            app.text13.Text = folderStr; % set folder name
        end

        % Button pushed function: pushbutton12
        function pushbutton12_Callback(app, event)
            % global variables
            global endAcquisition;
            endAcquisition = 1;
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % global variables
            global WARNINGS;
            WARNINGS = 0;                       % reset number of warnings
            app.text7.Text = '';
        end

        % Value changed function: edit9
        function edit9_Callback(app, event)
            % global variables
            global referenceLine;
            TempReferenceLine = str2double(app.edit9.Value);
            if(TempReferenceLine  < 250 || TempReferenceLine  > 800 || isnan(TempReferenceLine ))    
                % check if previous referenceLine exists
                if(isnan(referenceLine))
                    referenceLine = nan;   % nan for reference line
                    app.edit9.Value = '';
                else
                    app.edit9.Value = num2str(referenceLine);   % maintain reference line
                end
            else
                referenceLine = TempReferenceLine;  % update reference line 
                app.edit9.Value = num2str(referenceLine);
            end
        end

        % Value changed function: checkbox5
        function checkbox5_Callback(app, event)
            % global variables
            global saveSpectrum;
            saveSpectrum = app.checkbox5.Value;
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % global variables
            global serialList; global motorDetected; global serialPortSelected;
            global Monochromator;
            if(isempty(serialList))
                updateLog(app,'NO PORTS DETECTED.');
            else
                if(motorDetected)
                    disconnect(app,'Monochromator');
                end
                try
                    app.text6.Text = 'Detecting...';
                    updateWaitbar(app,0,1);
                    pause(0.1)
                    Monochromator = serial(serialPortSelected{1});
                    updateWaitbar(app,50,1);
                    pause(0.1)
                    % configure communication
                    Monochromator.Terminator = 'CR';    % based on datasheet
                    % open serial port connection
                    fopen(Monochromator);
                    updateWaitbar(app,100,1);
                    pause(0.1);
                    motorDetected = 1;
                    % establish communication
                    fprintf(Monochromator,' ');     % carriage return
                    flushinput(Monochromator)
                    fprintf(Monochromator,'');  
                    flushinput(Monochromator)
                catch
                    app.text6.Text = 'Ready.';
                    updateWaitbar(app,0,0);
                    updateLog(app,'NO MOTOR DETECTED.');
                    motorDetected = 0;
                end
                % default monochromator communication mode established
                app.text6.Text = 'Ready.';
                updateWaitbar(app,0,0);
            end
        end

        % Button pushed function: pushbutton14
        function pushbutton14_Callback(app, event)
            % global variables
            global totalSteps; global referenceLine; global prevWavelength;
            if(isnan(referenceLine))
                totalSteps = 0;     % reset total number of steps accumulated
            end
            prevWavelength = referenceLine; % update calibration
            app.text6.Text = 'EX Calibrated.';
            pause(0.1);
            app.text6.Text = 'Ready.';
        end

        % Button pushed function: pushbutton15
        function pushbutton15_Callback(app, event)
            % global variables
            global Spectrometer; global spectrometerDetected;
            % check if Spectrometer is detected
            if(~spectrometerDetected)
                updateLog(app,'NO SPECTROMETER DETECTED.');
            else
                % disconnect Spectrometer
                fclose(Spectrometer);
                delete(Spectrometer);
                spectrometerDetected = 0;
            end
        end

        % Button pushed function: pushbutton16
        function pushbutton16_Callback(app, event)
            % global variables
            global Monochromator; global motorDetected;
            % check if Spectrometer is detected
            if(~motorDetected)
                updateLog(app,'NO MOTOR DETECTED.');
            else
                % disconnect Monochromator
                fclose(Monochromator);
                delete(Monochromator);
                motorDetected = 0;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroup_Callback(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch(selectedButton.Text)
                case 'Emission'     % normal fluorescence (emission) scan
                    % only need Start EX and Monochromator 
                    app.MonochromatorCheckBox.Enable = 'on';
                    app.edit5.Enable = 'on';
                    app.edit6.Enable = 'off';
                    app.edit10.Enable = 'off';
                case 'Synchronous'  % synchronous fluorescence (EEM) scan
                    % need all fields except Monochromator
                    app.MonochromatorCheckBox.Enable = 'off';
                    app.edit5.Enable = 'on';
                    app.edit6.Enable = 'on';
                    app.edit10.Enable = 'on';
                case 'Raman'        % Raman scan
                    % do not need anything else
                    app.MonochromatorCheckBox.Enable = 'off';
                    app.edit5.Enable = 'off';
                    app.edit6.Enable = 'off';
                    app.edit10.Enable = 'off';
            end
        end

        % Value changed function: edit10
        function edit10_Callback(app, event)
            % global variables
            global RESOLUTION; global motorDetected;
            TempResolution = str2double(app.edit10.Value);
            if(TempResolution <= 0  || TempResolution > 100 || isnan(TempResolution))    
                RESOLUTION = nan;   % [ms] default integration time
                app.edit10.Value = 'Resolution';
            elseif(~motorDetected)
                updateLog(app,'NO MOTOR DETECTED.');
                RESOLUTION = nan;
                app.edit6.Value = 'Resolution';
            else
                % store resolution
                RESOLUTION = TempResolution;
            end
        end

        % Value changed function: edit11
        function edit11_Callback(app, event)
            % global variables
            global WINDOW;
            TempWindow = str2double(app.edit11.Value);
            if(TempWindow <= 0  || isnan(TempWindow))    
                WINDOW = nan;   % [ms] default window width
                app.edit11.Value = 'Width';
            else
                % store window width
                WINDOW = TempWindow;
            end
        end

        % Button pushed function: pushbutton17
        function pushbutton17_Callback(app, event)
            global ProcessingGUI;
            % initialize Processing GUI
            ProcessingGUI = PCAProcessorFig_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SpectrumAcquisition';
            app.axes1 = uiaxes(app.figure1);
            app.axes2 = uiaxes(app.figure1);
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 'Auto';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Value = 'Auto';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4_Callback, true);
            app.edit4.Value = 'Auto';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 'Auto';
            app.text1 = uilabel(app.figure1);
            app.text1.Text = 'Y:';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'X:';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Acquire Spectrum';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Spectrum';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'Calibrate';
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {};
            app.listbox1.Multiselect = 'on';
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Value = {};
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'Ports';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Text = 'Refresh';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'Pixel (#)'; 'Wavelength (nm)'; 'Fit'};
            app.uitable1.ColumnEditable = [true true false];
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Calibration';
            app.checkbox1 = uicheckbox(app.figure1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Apply Calibration';
            app.checkbox2 = uicheckbox(app.figure1);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Text = 'Background Subtraction';
            app.checkbox3 = uicheckbox(app.figure1);
            app.checkbox3.ValueChangedFcn = createCallbackFcn(app, @checkbox3_Callback, true);
            app.checkbox3.Text = 'Multiple Spectra';
            app.pushbutton8 = uibutton(app.figure1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.pushbutton8.Text = 'Set Background';
            app.pushbutton9 = uibutton(app.figure1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Text = 'Save';
            app.pushbutton10 = uibutton(app.figure1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Text = 'Load';
            app.checkbox4 = uicheckbox(app.figure1);
            app.checkbox4.ValueChangedFcn = createCallbackFcn(app, @checkbox4_Callback, true);
            app.checkbox4.Text = 'Continuous';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Not connected.';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Error Log';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = '';
            app.pushbutton11 = uibutton(app.uipanel1, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Text = 'Clear Log';
            app.pushbutton12 = uibutton(app.figure1, 'push');
            app.pushbutton12.ButtonPushedFcn = createCallbackFcn(app, @pushbutton12_Callback, true);
            app.pushbutton12.Text = 'X';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Scan Parameters';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = 'Scan Type:';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Text = 'Scan Range [nm]:';
            app.edit5 = uieditfield(app.uipanel2, 'text');
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.edit5.Value = 'Start EX';
            app.edit6 = uieditfield(app.uipanel2, 'text');
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit6.Value = 'Final EX';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'Integration time [ms]:';
            app.edit7 = uieditfield(app.uipanel2, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Value = '50';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Text = 'Average [#]:';
            app.edit8 = uieditfield(app.uipanel2, 'text');
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.edit8.Value = '1';
            app.ButtonGroup = uibuttongroup(app.uipanel2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Callback, true);
            app.EmissionButton = uiradiobutton(app.ButtonGroup);
            app.EmissionButton.Text = 'Emission';
            app.EmissionButton.Value = true;
            app.SynchronousButton = uiradiobutton(app.ButtonGroup);
            app.SynchronousButton.Text = 'Synchronous';
            app.RamanButton = uiradiobutton(app.ButtonGroup);
            app.RamanButton.Text = 'Raman';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Text = 'Calibrate EX:';
            app.pushbutton14 = uibutton(app.uipanel2, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @pushbutton14_Callback, true);
            app.pushbutton14.Text = 'Calibrate';
            app.edit9 = uieditfield(app.uipanel2, 'text');
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit9_Callback, true);
            app.edit10 = uieditfield(app.uipanel2, 'text');
            app.edit10.ValueChangedFcn = createCallbackFcn(app, @edit10_Callback, true);
            app.edit10.Value = 'Resolution';
            app.MedianFilterCheckBox = uicheckbox(app.uipanel2);
            app.MedianFilterCheckBox.Text = 'Median Filter';
            app.edit11 = uieditfield(app.uipanel2, 'text');
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit11_Callback, true);
            app.edit11.Value = 'Width';
            app.MonochromatorCheckBox = uicheckbox(app.uipanel2);
            app.MonochromatorCheckBox.Text = 'Monochromator';
            app.pushbutton6 = uibutton(app.figure1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Text = 'Detect EM';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Text = 'Detect EX';
            app.checkbox5 = uicheckbox(app.figure1);
            app.checkbox5.ValueChangedFcn = createCallbackFcn(app, @checkbox5_Callback, true);
            app.checkbox5.Text = 'Save Spectrum';
            app.pushbutton13 = uibutton(app.figure1, 'push');
            app.pushbutton13.ButtonPushedFcn = createCallbackFcn(app, @pushbutton13_Callback, true);
            app.pushbutton13.Text = 'Set Folder';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = 'Folder:';
            app.pushbutton15 = uibutton(app.figure1, 'push');
            app.pushbutton15.ButtonPushedFcn = createCallbackFcn(app, @pushbutton15_Callback, true);
            app.pushbutton15.Text = 'X';
            app.pushbutton16 = uibutton(app.figure1, 'push');
            app.pushbutton16.ButtonPushedFcn = createCallbackFcn(app, @pushbutton16_Callback, true);
            app.pushbutton16.Text = 'X';
            app.pushbutton17 = uibutton(app.figure1, 'push');
            app.pushbutton17.ButtonPushedFcn = createCallbackFcn(app, @pushbutton17_Callback, true);
            app.pushbutton17.Text = 'Processing (PCA)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectrumAcquisition_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @SpectrumAcquisition_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end