classdef PCAProcessorFig_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        text2        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        pushbutton3  matlab.ui.control.Button
        listbox1     matlab.ui.control.ListBox
        text6        matlab.ui.control.Label
        pushbutton4  matlab.ui.control.Button
        pushbutton6  matlab.ui.control.Button
        pushbutton5  matlab.ui.control.Button
        edit1        matlab.ui.control.EditField
        edit2        matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        checkbox1    matlab.ui.control.CheckBox
        edit3        matlab.ui.control.EditField
        text5        matlab.ui.control.Label
        axes3        matlab.ui.control.UIAxes
        text7        matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        text8        matlab.ui.control.Label
        pushbutton8  matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
        pushbutton1  matlab.ui.control.Button
        axes2        matlab.ui.control.UIAxes
        popupmenu1   matlab.ui.control.DropDown
        pushbutton2  matlab.ui.control.Button
        pushbutton7  matlab.ui.control.Button
        pushbutton9  matlab.ui.control.Button
        text9        matlab.ui.control.Label
    end

    
    methods (Access = private)
        % for adding a single, or set, of PCA objects in a given EXCEL (.xlsx) file;
        % assume that all columns of the EXCEL (.xlsx) file are labeled with an
        % object identifier
        function [DataforPCA, ObjectsforPCA, SpectraPerObjectforPCA, modifiedSpectra] = PCASet(app,Data,Spectra,DataforPCA,ObjectsforPCA,SpectraPerObjectforPCA,AllObjectsAdded)
            if(isempty(DataforPCA))
                modifiedSpectra = [];                                           % flush if empty
            else
                modifiedSpectra = AllObjectsAdded;                              % modified Spectra List taken from active PCA window
            end
            Spectra = Spectra(2:length(Spectra));                               % remove wavelengths column
            for i=1:length(Spectra)
                [strModified] = processStr(app,cellstr(Spectra(i)));        		% obtain modified strings
                contains = checkObject(app,modifiedSpectra,strModified);       % check if object is already contained
                modifiedSpectra = [modifiedSpectra cellstr(strModified)];           	% update modified list
                if(isempty(DataforPCA))     % data is empty, so push object
                    DataforPCA = [DataforPCA Data(:,1:2)];                      % push wavelengths column and one spectrum 
                    ObjectsforPCA = [ObjectsforPCA cellstr(strModified)];       % add one object identifier
                    SpectraPerObjectforPCA = [SpectraPerObjectforPCA 1];        % increment for given identifier
                elseif(contains)
                    % essentially checking if Object name is already contained in the PCA
                    % list; push, but not at the end (must copy over elements)
                    indexToIncrement = find(strcmp(ObjectsforPCA,cellstr(strModified)));    % find index for incrementing object counter
                    startingIndex = sum(SpectraPerObjectforPCA(1:indexToIncrement));
                    ModifiedData = [DataforPCA(:,1:startingIndex+1) Data(:,i+1) DataforPCA(:,startingIndex+2:size(DataforPCA,2))];    % account for added data
                    DataforPCA = ModifiedData;  % copy elements over
                    SpectraPerObjectforPCA(indexToIncrement) = SpectraPerObjectforPCA(indexToIncrement)+1;  % increment
                else    % otherwise, simply push new element
                    DataforPCA = [DataforPCA Data(:,i+1)];      
                    ObjectsforPCA = [ObjectsforPCA cellstr(strModified)];
                    SpectraPerObjectforPCA = [SpectraPerObjectforPCA 1];    % add new object
                end
            end
        end
        
        function contains = checkObject(~,strMat, str)      % check if str is contained already in strMat
            contains = 0;
            for j=1:length(strMat)
                if(strcmpi(strMat(j),str))  % ignore case
                    contains = 1;
                end
            end
        end
        
        function [strModified] = processStr(~,strOriginal)
            strModified = '';
            WIDTH_NUMERIC_IDENTIFIER = 2;   % right now, width is ~ 2 to allow up to 100 objects (could be changed)
            str = strOriginal{1};           % cell to string array
            for k=1:length(str)
                if(~isstrprop(str(k),'digit') || k<length(str)-WIDTH_NUMERIC_IDENTIFIER)
                    strModified = [strModified upper(str(k))];      % modify string to simplify processing
                end
            end
        end
        
        function ComputePCA(app)
            % global variables
            global DataPCA; global Objects; global SpectraPerObject;
            global CorrectedDataPCA;
            
            % ROI information
            global PCALowerIndex; global PCAUpperIndex;
            
            % SCORE and COEFFECIENTS vectors
            global COEFF; global SCORE;
            
            % data corrections
            global peaksCorrected;
            
            % legend
            global LegendObj;   % for score plot
            
            app.text7.Text = 'Performing PCA...';
            updateWaitbar(app,0,1);
            pause(0.1);
            if(length(Objects) > 1)
                if(PCALowerIndex == 0 || PCAUpperIndex == 0)    % default is to use full range; satisfy boundary conditions
                    PCALowerIndex = 1;
                    PCAUpperIndex = size(DataPCA,1);
                end
                if(peaksCorrected)  % use corrected spectra
                    DataMat = CorrectedDataPCA;
                else
                    DataMat = DataPCA;
                end
                 % transpose intensities so that variation is measured among
                 % irradiation dose (and hence degree of inactivation)
                [COEFF, SCORE] = pca((DataMat(PCALowerIndex:PCAUpperIndex,2:size(DataPCA,2)))');  % transpose intensities
                % obtain PC1 and PC2 only (for now)
                PC1 = [];
                PC2 = [];
                currIndex = 1;
            
                updateWaitbar(app,25,1);
                pause(0.1);
            
                % plot principal components for Training Set
                for i=1:length(SpectraPerObject)
                    nextIndex = currIndex+SpectraPerObject(i)-1;
                    PC1 = [PC1 SCORE(currIndex:nextIndex, 1)'];    % PC1 grows horizontally
                    PC2 = [PC2 SCORE(currIndex:nextIndex, 2)'];    % PC2 grows horizontally
                    currIndex = nextIndex+1;
                end
            
                updateWaitbar(app,50,1);
                pause(0.1);
            
                % similar method as above for correlating designation with number of
                % objects
                currIndex = 1;
                for j=1:length(SpectraPerObject)
                    nextIndex = currIndex+SpectraPerObject(j)-1;
                    plot(app.axes1, PC1(currIndex:nextIndex), PC2(currIndex:nextIndex), '<','MarkerSize',10);
                    % keep plots (hold on)
                    set(app.axes1, 'NextPlot', 'add');
                    currIndex = nextIndex+1;
                end
            
                updateWaitbar(app,75,1);
                pause(0.1);
            
                updateWaitbar(app,100,1);
                pause(1);
            
                % update legend, axes
                LegendObj = legend(app.axes1, Objects);
                xlabel(app.axes1, 'Principal Component 1');
                ylabel(app.axes1, 'Principal Component 2');
                % allow plot to be regraphed
                set(app.axes1, 'NextPlot', 'replace');
                % update components plots
                UpdateComponents(app);
                % fix plots
                fixScorePlot(app);
                fixComponentsPlot(app);
                
                % reset
                updateWaitbar(app,0,0);
                app.text7.Text = 'Ready.';
            else
                % reset
                updateWaitbar(app,0,0);
                app.text7.Text = 'Ready.';
                updateLog(app,'More PCA objects are needed.');
                % clear plots, clear coefficient matrix
                cla(app.axes1);
                cla(app.axes2);
                COEFF = [];
                delete(LegendObj);
            end
        end
        
        function updateLog(app, str)
            % global variables
            global WARNINGS;
            WARNINGS = WARNINGS + 1;
            currentLog = app.text8.Text;   % 2D character vector
            row = 1;
            while(size(currentLog,1) >= row)
                if(~isempty(currentLog))
                    if(sum(isspace(currentLog(row,:)))==size(currentLog,2)) % empty
                        currentLog(row,:) = []; % delete
                    end
                end
                row = row + 1;
            end
            % currentLog
            error = sprintf('%s\n%s\n', string(currentLog),string([num2str(WARNINGS) '. ' str]));
            app.text8.Text = error; % update log
        end
        
        function UpdateComponents(app)
                global DataPCA; global COEFF;
                global PCALowerIndex; global PCAUpperIndex;
                % components plot; populate popup menu
                ComponentsList = [];
                for k=1:size(COEFF,2)   % columns of COEFF correspond to number of PCs
                    str = ['Principal Component ' num2str(k)];
                    ComponentsList = [ComponentsList cellstr(str)];
                end
                app.popupmenu1.Items = ComponentsList;  % update menu
                % plot first component by default with ROI
                plot(app.axes2,DataPCA(PCALowerIndex:PCAUpperIndex,1),COEFF(:,1));
                xlabel(app.axes2,'Wavelength (nm)');
                ylabel(app.axes2,'Intensity (a.u.)');
        end
        
        function closestIndex = findClosestIndex(~, mat, value)
               	closestIndex = 0;
                if(value < mat(1) || value > mat(length(mat)))  % out of bounds (lower or upper bound)
                    closestIndex = -1;
                else
                    DIFFERENCE = inf;
                    for i=1:length(mat)
                        if(abs(mat(i)-value) < DIFFERENCE)      % update minimal difference
                            closestIndex = i;
                            DIFFERENCE = abs(mat(i)-value);
                        end
                    end
                end
        end
        
        function fixComponentsPlot(app)
                set(app.axes2,'FontSize',10);
                set(app.axes2,'FontWeight','bold');
                set(findall(app.axes2, 'Type', 'Line'),'LineWidth',1.5);
        end
        
        function fixScorePlot(app)
                set(app.axes1,'FontSize',10);
                set(app.axes1,'FontWeight','bold');
                set(findall(app.axes1, 'Type', 'Line'),'LineWidth',1.5);
        end
        
        function updateWaitbar(app, PROGRESS, TEXT)
            global BAR; global TAG;
            delete(BAR)     % delete current waitbar object
            delete(TAG);    % delete current text/tag object
            TAG = [];
            WIDTH = 100;    % [%] waitbar width
            BAR = rectangle(app.axes3,'Position',[0,0,(round(1000*PROGRESS/WIDTH))+1,20],'FaceColor','g');
            if(TEXT)        % include text on waitbar during processes
                TAG = text(app.axes3,450,10,[num2str(round(100*PROGRESS/WIDTH)),'%'],'FontWeight','bold');
            end
        end
        
        function [fileList] = parseFiles(~)
            % general File Parser capable of reading all files in a
            % given folder
            files = uigetdir();
            files = dir(files);     % obtain all files within given directory
            fileNames = nan(1,length(files));
            for i=1:length(files)
                if(~files(i).isdir)
                    if(~isempty(fileNames))
                        fileNames(i) = [fileNames ' ' files(i).name];
                    else
                        fileNames(i) = files(i).name;
                    end
                end
            end

            % parse filenames properly by finding spaces
            parsePoints = [];
            for j=1:length(fileNames)
                if(j>4)
                    if(strcmp(fileNames(j),' ') && (strcmp(fileNames(j-4:j-1),'xlsx'))) % known .xlsx extension
                        parsePoints = [parsePoints j];
                    end
                end
            end

            fileList = nan(1,length(parsePoints));
            bottomIndex = 1;
            for k=1:length(parsePoints)
                fileList(k) = cellstr(fileNames(bottomIndex:parsePoints(k)-1));
                bottomIndex = parsePoints(k)+1;         % update bottom index
            end
            % final file name
            fileList = [filesToRead cellstr(fileNames(bottomIndex:length(fileNames)))];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PCAProcessorFig_OpeningFcn(app)
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to PCAProcessorFig (see VARARGIN)
            
            % plot traits
            fixScorePlot(app);
            fixComponentsPlot(app);
            
            % global variables
            global PCALowerIndex; global PCAUpperIndex; global peaksCorrected; global CorrectionWidth;
            % initialize relevant variables
            PCALowerIndex = 0;
            PCAUpperIndex = 0;
            peaksCorrected = 0;
            CorrectionWidth = 0;
            
            % create visual waitbar
            waitbarAx = app.axes3;
            set(waitbarAx,'Xtick',[],'Ytick',[],'Xlim',[0 1000]);
            updateWaitbar(app,0,0);
            
            % resize
            app.figure1.Resize = 'on';
            
            % update waitbar
            app.text7.Text = 'Ready.';
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % global variables
            global peaksCorrected;
            peaksCorrected = app.checkbox1.Value;
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % global variables
            global PCAXLower; global DataPCA; global PCALowerIndex;
            if(isnan(str2double(app.edit1.Value)))
                updateLog(app,'Invalid numeric value.');
                PCALowerIndex = 1;
            elseif(isempty(DataPCA))
                updateLog(app,'No Training Set loaded.');
                PCALowerIndex = 1;
            else
                PCAXLower = str2double(app.edit1.Value);
                closestIndex = findClosestIndex(app,DataPCA(:,1),PCAXLower);    % find closest index in wavelengths vector
                if(closestIndex == -1)    % out of bounds
                    updateLog(app,'Lower ROI is out of bounds.');
                    PCALowerIndex = 1;          % boundary condition
                else
                    PCALowerIndex = closestIndex;
                end
            end
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % global variables
            global PCAXUpper; global DataPCA; global PCAUpperIndex;
            if(isnan(str2double(app.edit2.Value)))
                updateLog(app,'Invalid numeric value.');
                PCAUpperIndex = size(DataPCA,1);
            elseif(isempty(DataPCA))
                updateLog(app,'No Training Set loaded.');
                PCAUpperIndex = size(DataPCA,1);
            else
                PCAXUpper = str2double(app.edit2.Value);
                closestIndex = findClosestIndex(app,DataPCA(:,1),PCAXUpper);  % find closest index in wavelengths vector
                if(closestIndex == -1)    % out of bounds
                    updateLog(app,'Upper ROI is out of bounds.');
                    PCAUpperIndex = size(DataPCA,1);        % boundary condition
                else
                    PCAUpperIndex = closestIndex;
                end
            end
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % global variables
            global DataPCA;
            global CorrectionWidth; global CorrectedDataPCA;
            if(isnan(str2double(app.edit3.Value)) || str2double(app.edit3.Value) < 0)
                updateLog(app,'Invalid numeric value.');
                CorrectedDataPCA = DataPCA;         % default action is to maintain spectra
            elseif(isempty(DataPCA))
                updateLog(app,'No Training Set loaded.');
                CorrectedDataPCA = DataPCA;         % default action is to maintain spectra
            else
                CorrectionWidth = round(str2double(app.edit3.Value));   % round to nearest integer
                app.edit3.Value = num2str(round(CorrectionWidth));
                CorrectedDataPCA = [DataPCA(:,1) nan(size(DataPCA,1),size(DataPCA,2))];   % copy over wavelengths vector
                for i=2:size(DataPCA,2)             % iterate through spectra
                    correctedSpectra = medfilt1(DataPCA(:,i),CorrectionWidth);  % 1-D median filter
                    CorrectedDataPCA(:,i) = correctedSpectra;                   % copy over corrected spectra
                end
            end
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % global variables
            global Objects;
            global ObjectsSelected;
            global SelectedObjectStrs;
            % need to account for multi-selection
            selections = app.listbox1.Value;     % fetch the array of indices of Objects selected (multi-selection)
            ObjectsSelected = [];
            for i=1:length(selections)
                for j=1:length(Objects)
                    if(strcmp(selections{i},Objects{j}))
                        ObjectsSelected = [ObjectsSelected j];
                    end
                end
            end
            SelectedObjectStrs = [];
            for k=1:length(ObjectsSelected)
                SelectedObjectStrs = [SelectedObjectStrs Objects(ObjectsSelected(k))];
            end
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % global variables
            global COEFF;   % for coefficients plot
            global DataPCA; global PCALowerIndex; global PCAUpperIndex;
            componentSelected = app.popupmenu1.Value;
            componentList = app.popupmenu1.Items;
            index = 1;
            while(~strcmp(componentSelected,componentList{index}))
                index = index + 1;
            end
            if(~isempty(DataPCA) && ~isempty(COEFF))
                % update components plot with correct ROI
                plot(app.axes2, DataPCA(PCALowerIndex:PCAUpperIndex,1), COEFF(:,index));
                xlabel(app.axes2, 'Wavelength (nm)');
                ylabel(app.axes2, 'Intensity (a.u.)');
                % fix plot
                fixComponentsPlot(app);
            else
                updateLog(app,'No Training Set loaded.');
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % global variables
            global DataPCA; global Objects; global SpectraPerObject; global AllObjectsAdded;
            filename = uigetfile;
            [Data, Spectra] = xlsread(filename);
            app.text7.Text = 'Loading PCA Objects...';
            updateWaitbar(app,0,1);
            pause(0.1);
            [DataPCA, Objects, SpectraPerObject, AllObjectsAdded] = PCASet(app,Data, Spectra, DataPCA, Objects, SpectraPerObject, AllObjectsAdded);
            updateWaitbar(app,50,1);
            pause(0.1);
            app.listbox1.Items = Objects;       % update Objects List
            updateWaitbar(app,100,1);
            pause(0.1);
            updateWaitbar(app,0,0);
            app.text7.Text = 'Ready.';
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % global variables
            global DataPCA; global Objects; global SpectraPerObject; global AllObjectsAdded;
            global ObjectsSelected;
            global SelectedObjectStrs;
            
            % need to adjust DataPCA, Objects, SpectraPerObject, and AllObjectsAdded
            if(isempty(DataPCA))
                updateLog(app,'No Training Set loaded.');
            else
                % adjust DataPCA, Objects, SpectraPerObject, and
                % AllObjectsAdded
                DataPCAAdjusted = DataPCA(:,1);     % copy wavelengths vector
                ObjectsAdjusted = [];
                initialCol = 2;     % initial spectrum in matrix
                for i=1:length(Objects)
                    strFound = 0;
                    for j=1:length(SelectedObjectStrs)
                        if(strcmp(Objects(i),SelectedObjectStrs(j)))    % match found
                            strFound = 1;       % set flag bit
                        end
                    end
                    if(~strFound)
                        DataPCAAdjusted = [DataPCAAdjusted DataPCA(:,initialCol:initialCol+SpectraPerObject(i)-1)];
                        ObjectsAdjusted = [ObjectsAdjusted Objects(i)];
                    end
                    initialCol = initialCol + SpectraPerObject(i);
                end
                DataPCA = DataPCAAdjusted;  % adjust Data matrix
                Objects = ObjectsAdjusted;  % adjust Objects list
                
                % adjust SpectraPerObject
                SpectraPerObject(ObjectsSelected) = [];  % delete corresponding spectrum counters
                
                % adjust AllObjectsAdded
                tempAllObjectsAdded = {};
                for h=1:length(AllObjectsAdded)
                    strFound = 0;
                    for k=1:length(SelectedObjectStrs)
                        if(strcmp(AllObjectsAdded(h),SelectedObjectStrs(k)))
                            strFound = 1;
                        end
                    end
                    if(~strFound)
                        tempAllObjectsAdded = [tempAllObjectsAdded cellstr(AllObjectsAdded(j))];
                    end
                end
                AllObjectsAdded = tempAllObjectsAdded;  % adjust AllObjectsAdded list
            
                % adjust values field as needed after deleting element
                app.listbox1.Items = Objects;
                app.listbox1.Value = {};
                
                % update score and components plot
                ComputePCA(app);
            end
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            % global variables
            global PCALowerIndex; global PCAUpperIndex; global DataPCA; 
            global CorrectionWidth; global CorrectedDataPCA;
            if(isempty(DataPCA))
                updateLog(app,'No Training Set loaded.');
            % check inputs, satisfy boundary conditions
            else
                if(PCALowerIndex == 0 || PCAUpperIndex == 0)
                    PCALowerIndex = 1;
                    PCAUpperIndex = size(DataPCA,1);
                end
                if(CorrectionWidth == 0)         % simply copy over data if correction is zero
                    CorrectedDataPCA = DataPCA;
                end
                % perform PCA
                ComputePCA(app);
            end
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % global variables
            global DataPCA; global Objects; global SpectraPerObject; global AllObjectsAdded;
            fileList = parseFiles(app);     % obtain cell of file names for processing
            app.text7.Text = 'Loading PCA Objects...';
            updateWaitbar(app,0,1);
            pause(0.1);
            for i=1:length(fileList)
                [TempData,TempSpectra] = xlsread(filelist);
                [DataPCA, Objects, SpectraPerObject, AllObjectsAdded] = PCASet(app,TempData, TempSpectra, DataPCA, Objects, SpectraPerObject, AllObjectsAdded);   % process all files
            end
            updateWaitbar(app,50,1);
            pause(0.1);
            app.listbox1.Items = Objects;       % update Objects List
            updateWaitbar(app,100,1);
            pause(0.1);
            updateWaitbar(app,0,0);
            app.text7.Text = 'Ready.';
        end

        % Callback function
        function pushbutton1_Callback(app, event)
            % global variables
            global Objects;
            % copy and undock plot
            xdata = app.axes1.UserData(:,1);   
            ydata = app.axes1.UserData(:,2);
            xlab = app.axes1.XLabel;
            ylab = app.axes1.YLabel;
            figure
            set(gca,'ActivePositionProperty','outerposition')
            set(gca,'Units','normalized')
            set(gca,'OuterPosition',[0 0 1 1])
            set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
            legendToCopy = Objects;
            plot(gca,xdata,ydata);
            xlabel(gca,xlab);
            ylabel(gca,ylab);
            legend(gca,legendToCopy);
            % plot traits
            set(gca,'FontSize',10);
            set(gca,'FontWeight','bold');
            set(findall(gca, 'Type', 'Line'),'LineWidth',1.5);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % copy and undock plot
            xdata = app.axes2.UserData(:,1);   
            ydata = app.axes2.UserData(:,2);
            xlab = app.axes2.XLabel;
            ylab = app.axes2.YLabel;
            figure
            set(gca,'ActivePositionProperty','outerposition')
            set(gca,'Units','normalized')
            set(gca,'OuterPosition',[0 0 1 1])
            set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
            plot(gca,xdata,ydata);
            xlabel(gca,xlab);
            ylabel(gca,ylab);
            legend(gca,legendToCopy);
            % plot traits
            set(gca,'FontSize',10);
            set(gca,'FontWeight','bold');
            set(findall(gca, 'Type', 'Line'),'LineWidth',1.5);
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % global variables
            global folderPath; 
            % generate folder path
            folderPath = uigetdir();
            parts = regexp(folderPath,'\','split'); 
            folderStr = ['Folder: ' parts{length(parts)}];
            app.text9.Text = folderStr; % set folder name
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % global variables
            global folderPath; global COEFF; global SCORE; global SpectraPerObject; global Objects; global DataPCA;
            global PCALowerIndex; global PCAUpperIndex; global CorrectionWidth; global peaksCorrected;
            % check that folder exists
            if(isnan(folderPath))
                updateLog(app,'NO FOLDER SET.');
            % check that PCA is performed
            elseif(isempty(COEFF) || isempty(SCORE))
                updateLog(app,'NO PCA PERFORMED.');
            else
                % generate full datapath
                app.text7.Text = 'Saving...';
                updateWaitbar(app,0,1);
                details = cellstr(datetime);    % fetch current date and time
                dateTime = details{1};
                spaceIndex = strfind(dateTime, ' ');  % find space
                currentDate = dateTime(1:spaceIndex);   % date string
                currentTime = dateTime(spaceIndex+1:length(dateTime));  % time string
                colons = strfind(currentTime,':');  % return 2 colons
                currentTime(colons(1)) = '-';
                currentTime(colons(2)) = '-';
                strPath = ['PCA ' currentDate currentTime '.xlsx'];
                if(~isempty(folderPath))
                    toWrite = fullfile(folderPath,strPath);
                else
                    toWrite = strPath;
                end
                % extract principal components and object names from Training Set
                updateWaitbar(app,25,1);
                PC1 = [];
                PC2 = [];
                currIndex = 1;
                for i=1:length(SpectraPerObject)
                    nextIndex = currIndex+SpectraPerObject(i)-1;
                    PC1 = [PC1 SCORE(currIndex:nextIndex, 1)'];    % PC1 grows horizontally
                    PC2 = [PC2 SCORE(currIndex:nextIndex, 2)'];    % PC2 grows horizontally
                    currIndex = nextIndex+1;
                end
                % write score plots
                updateWaitbar(app,50,1);
                charIndex = 65;   % 'A'
                currIndex = 1;
                for i=1:length(SpectraPerObject)
                    nextIndex = currIndex+SpectraPerObject(i)-1;
                    xlswrite(toWrite,Objects(i),'Sheet1',[char(charIndex) '1']);
                    xlswrite(toWrite,cellstr('PC1'),'Sheet1',[char(charIndex+1) '1']);
                    xlswrite(toWrite,PC1(currIndex:nextIndex)','Sheet1',[char(charIndex+1) '2']);
                    xlswrite(toWrite,cellstr('PC2'),'Sheet1',[char(charIndex+2) '1']);
                    xlswrite(toWrite,PC2(currIndex:nextIndex)','Sheet1',[char(charIndex+2) '2']);
                    currIndex = nextIndex+1;
                    charIndex = charIndex+3;
                end
                xlswrite(toWrite,cellstr('Wavelength (nm)'),'Sheet2','A1')
                xlswrite(toWrite,DataPCA(PCALowerIndex:PCAUpperIndex,1),'Sheet2','A2'); % write wavelengths
                % write component plots
                xlswrite(toWrite,cellstr('PC1'),'Sheet2','B1');
                xlswrite(toWrite,COEFF(:,1),'Sheet2','B2');
                xlswrite(toWrite,cellstr('PC2'),'Sheet2','C1');
                xlswrite(toWrite,COEFF(:,2),'Sheet2','C2');
                % tags (ROI, median window width, etc.)
                updateWaitbar(app,75,1);
                ROITag = ['ROI: ' num2str(DataPCA(PCALowerIndex)) '-' num2str(DataPCA(PCAUpperIndex)) ' nm'];
                if(peaksCorrected)
                    CorrectionWidthTag = ['Median Filter Width: ' num2str(CorrectionWidth) ' points.'];
                else
                    CorrectionWidthTag = ['No Median Filter.'];
                end
                % write tags
                xlswrite(toWrite,cellstr(ROITag),'Sheet3','A1');
                xlswrite(toWrite,cellstr(CorrectionWidthTag),'Sheet3','A2');
                updateWaitbar(app,100,1);
                pause(0.1);
                updateWaitbar(app,0,0);
                app.text7.Text = 'Ready';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'PCAProcessorFig';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Score Plot (PC1 and PC2)';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Component Plot';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'Add Object';
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {'Listbox'};
            app.listbox1.Multiselect = 'on';
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Value = {'Listbox'};
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Training Set';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Text = 'Delete Object';
            app.pushbutton6 = uibutton(app.figure1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Text = 'Process Training Set';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Text = 'Add Set';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 'Auto';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 'Auto';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'ROI:';
            app.checkbox1 = uicheckbox(app.figure1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Enable Correction';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Value = 'Auto';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Correction Window:';
            app.axes3 = uiaxes(app.figure1);
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'Not connected.';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Error Log';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = '';
            app.pushbutton8 = uibutton(app.uipanel1, 'push');
            app.pushbutton8.Text = 'Clear Log';
            app.axes1 = uiaxes(app.figure1);
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.Text = 'U';
            app.axes2 = uiaxes(app.figure1);
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Select Component...'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Value = 'Select Component...';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'U';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Text = 'Set Folder';
            app.pushbutton9 = uibutton(app.figure1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Text = 'Save';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Folder:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCAProcessorFig_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @PCAProcessorFig_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end